classdef GeographicAxesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeographicAxesAppUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ZoomLevelSlider            matlab.ui.control.Slider
        ZoomLevelSliderLabel       matlab.ui.control.Label
        CellTowersPlotSwitch       matlab.ui.control.Switch
        CellTowersPlotSwitchLabel  matlab.ui.control.Label
        LayersPanel                matlab.ui.container.Panel
        CitiesCheckBox             matlab.ui.control.CheckBox
        CellTowersCheckBox         matlab.ui.control.CheckBox
        StateBordersCheckBox       matlab.ui.control.CheckBox
        BasemapCheckBox            matlab.ui.control.CheckBox
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private) 
        GeographicAxesObject        matlab.graphics.axis.GeographicAxes
        StateBordersLineObject      matlab.graphics.chart.primitive.Line
        CitiesScatterObject         matlab.graphics.chart.primitive.Scatter 
        CellTowerDensityPlotObject  matlab.graphics.chart.primitive.DensityPlot 
        CellTowerScatterObject      matlab.graphics.chart.primitive.Scatter 
    end
    
    methods (Access = private)
        
        function toggleCellTowersPlot(app)
            % Get values of check box and switch 
            checkBoxValue = app.CellTowersCheckBox.Value;
            switchValue = app.CellTowersPlotSwitch.Value;

            % If check box is cleared, make both plots invisible
            if checkBoxValue == false
                app.CellTowerDensityPlotObject.Visible = "off";
                app.CellTowerScatterObject.Visible = "off";
               
            % If check box is selected and switch points to density plot,
            % make density plot visible and scatter plot invisible
            elseif checkBoxValue == true && switchValue == "Density"
                app.CellTowerDensityPlotObject.Visible = "on";
                app.CellTowerScatterObject.Visible = "off";

            % If check box is selected and switch points to scatter plot,
            % make density plot visible and scatter plot invisible
            elseif checkBoxValue == true && switchValue == "Scatter"
                app.CellTowerDensityPlotObject.Visible = "off";
                app.CellTowerScatterObject.Visible = "on";
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create geographic axes
            app.GeographicAxesObject = geoaxes(app.RightPanel, ...
                NextPlot="add",Basemap="streets-light", ...
                MapCenter=[37.1 -119.5],ZoomLevel=5.7);

            % Disable panning and zooming
            app.GeographicAxesObject.InteractionOptions.PanSupported = "off";
            app.GeographicAxesObject.InteractionOptions.ZoomSupported = "off";

            % Add title
            title(app.GeographicAxesObject,"Cell Towers in California")

            % Display state outlines
            borders = load("usastates.mat");
            bordersLat = [borders.usastates.Lat];
            bordersLon = [borders.usastates.Lon];
            app.StateBordersLineObject = geoplot(app.GeographicAxesObject, ...
                bordersLat,bordersLon,Color="#888888");

            % Display city locations
            citiesLat = [37.775 38.582 36.738 34.052 32.716];
            citiesLon = [-122.42 -121.494 -119.787 -118.244 -117.161];
            app.CitiesScatterObject = geoscatter(app.GeographicAxesObject, ...
                citiesLat,citiesLon,"filled",SizeData=150, ...
                Marker="pentagram",LineWidth=0.9, ...
                MarkerFaceColor="#FFE864",MarkerEdgeColor="#453304");

            % Load cell tower locations
            counties = load("cellularTowers.mat");
            cellTowersLat = counties.cellularTowers.Latitude;
            cellTowersLon = counties.cellularTowers.Longitude;

            % Create density plot of cell tower locations
            cellTowerColor = "#54B6FF";
            app.CellTowerDensityPlotObject = geodensityplot(app.GeographicAxesObject, ...
                cellTowersLat,cellTowersLon,Radius=50000, ...
                FaceColor=cellTowerColor);

            % Create scatter plot of cell tower locations
            app.CellTowerScatterObject = geoscatter(app.GeographicAxesObject, ...
                cellTowersLat,cellTowersLon,Marker=".", ...
                MarkerEdgeColor=cellTowerColor,MarkerEdgeAlpha=0.5, ...
                Visible="off");

            % Reorder plots
            uistack(app.CitiesScatterObject,"top")
        end

        % Value changed function: BasemapCheckBox
        function BasemapCheckBoxValueChanged(app, event)
            % Change visibility of basemap based on check box
            value = app.BasemapCheckBox.Value;
            if value
                app.GeographicAxesObject.Basemap = "streets-light";
            else
                app.GeographicAxesObject.Basemap = "none";
            end
        end

        % Value changed function: StateBordersCheckBox
        function StateBordersCheckBoxValueChanged(app, event)
            % Change visibility of state borders based on check box
            value = app.StateBordersCheckBox.Value;
            app.StateBordersLineObject.Visible = value;
        end

        % Value changed function: CitiesCheckBox
        function CitiesCheckBoxValueChanged(app, event)
            % Change visibility of cities based on check box
            value = app.CitiesCheckBox.Value;
            app.CitiesScatterObject.Visible = value;
        end

        % Value changed function: CellTowersCheckBox
        function CellTowersCheckBoxValueChanged(app, event)
            % Change visibility of cell towers based on check box
            toggleCellTowersPlot(app)
        end

        % Value changed function: CellTowersPlotSwitch
        function CellTowersPlotSwitchValueChanged(app, event)
            % Change cell towers plot based on switch
            toggleCellTowersPlot(app)
        end

        % Value changed function: ZoomLevelSlider
        function ZoomLevelSliderValueChanged(app, event)
            % Change zoom level based on slider
            value = app.ZoomLevelSlider.Value;
            app.GeographicAxesObject.ZoomLevel = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GeographicAxesAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480.00000000000006, 480.00000000000006};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {194, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeographicAxesAppUIFigure = uifigure('Visible', 'off');
            app.GeographicAxesAppUIFigure.Name = 'Geographic Axes App';
            app.GeographicAxesAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GeographicAxesAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LayersPanel = uipanel(app.LeftPanel);
            app.LayersPanel.Title = 'Layers';
            app.BasemapCheckBox = uicheckbox(app.LayersPanel);
            app.BasemapCheckBox.ValueChangedFcn = createCallbackFcn(app, @BasemapCheckBoxValueChanged, true);
            app.BasemapCheckBox.Text = 'Basemap';
            app.BasemapCheckBox.Value = true;
            app.StateBordersCheckBox = uicheckbox(app.LayersPanel);
            app.StateBordersCheckBox.ValueChangedFcn = createCallbackFcn(app, @StateBordersCheckBoxValueChanged, true);
            app.StateBordersCheckBox.Text = 'State Borders';
            app.StateBordersCheckBox.Value = true;
            app.CellTowersCheckBox = uicheckbox(app.LayersPanel);
            app.CellTowersCheckBox.ValueChangedFcn = createCallbackFcn(app, @CellTowersCheckBoxValueChanged, true);
            app.CellTowersCheckBox.Text = 'Cell Towers';
            app.CellTowersCheckBox.Value = true;
            app.CitiesCheckBox = uicheckbox(app.LayersPanel);
            app.CitiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CitiesCheckBoxValueChanged, true);
            app.CitiesCheckBox.Text = 'Cities';
            app.CitiesCheckBox.Value = true;
            app.CellTowersPlotSwitchLabel = uilabel(app.LeftPanel);
            app.CellTowersPlotSwitchLabel.Text = 'Cell Towers Plot';
            app.CellTowersPlotSwitch = uiswitch(app.LeftPanel, 'slider');
            app.CellTowersPlotSwitch.Items = {'Density', 'Scatter'};
            app.CellTowersPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @CellTowersPlotSwitchValueChanged, true);
            app.CellTowersPlotSwitch.Value = 'Density';
            app.ZoomLevelSliderLabel = uilabel(app.LeftPanel);
            app.ZoomLevelSliderLabel.Text = 'Zoom Level';
            app.ZoomLevelSlider = uislider(app.LeftPanel);
            app.ZoomLevelSlider.Limits = [5 10];
            app.ZoomLevelSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomLevelSliderValueChanged, true);
            app.ZoomLevelSlider.Value = 5.7;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.GeographicAxesAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeographicAxesApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeographicAxesAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeographicAxesAppUIFigure)
        end
    end
end