classdef ColorPickerComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (GetAccess = ?matlab.unittest.TestCase, SetAccess = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        Button      matlab.ui.control.Button
        BlueField   matlab.ui.control.NumericEditField
        GreenField  matlab.ui.control.NumericEditField
        RedField    matlab.ui.control.NumericEditField
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ColorChanged % When color changes
    end

    properties (Access = public)
        Value (1,3) double {mustBeInRange(Value, 0,1)} = [0 0 0]; % RBG color value
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BlueField, GreenField, RedField
        function FieldValueChanged(comp, event)
            comp.Value = [comp.RedField.Value,comp.GreenField.Value,comp.BlueField.Value];
            notify(comp,"ColorChanged")
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            comp.Value = uisetcolor(comp.Value);
            notify(comp,"ColorChanged")
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            comp.RedField.Value = comp.Value(1);
            comp.GreenField.Value = comp.Value(2);
            comp.BlueField.Value = comp.Value(3);
            comp.Button.BackgroundColor = comp.Value;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 210 25];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.RedField = uieditfield(comp.GridLayout, 'numeric');
            comp.RedField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.GreenField = uieditfield(comp.GridLayout, 'numeric');
            comp.GreenField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.BlueField = uieditfield(comp.GridLayout, 'numeric');
            comp.BlueField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FieldValueChanged, true);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Text = '';
        end
    end
end