classdef DialogAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        DropDown                  matlab.ui.control.DropDown
        ColormapDropDownLabel     matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        SampleSizeEditFieldLabel  matlab.ui.control.Label
        Button                    matlab.ui.control.Button
    end


    properties (Access = private) 
        MainApp % Main app 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, sz, c)
            % Update UI with input values
            app.EditField.Value = sz;
            app.DropDown.Value = c;

            % Store main app in property
            app.MainApp = mainapp;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
            updateplot(app.MainApp, app.EditField.Value, app.DropDown.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Options button in main app, if the app is
            % still open
            if isvalid(app.MainApp)
                app.MainApp.OptionsButton.Enable = "on";
            end
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            app.SampleSizeEditFieldLabel = uilabel(app.GridLayout);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Limits = [2 1000];
            app.EditField.Value = 35;
            app.ColormapDropDownLabel = uilabel(app.GridLayout);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Parula', 'Jet', 'Winter', 'Cool'};
            app.DropDown.Value = 'Parula';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppExample(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end