classdef FileSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        Button      matlab.ui.control.Button
        EditField   matlab.ui.control.EditField
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        PathChanged % Event that triggers when a new path is specified
    end

    properties (Access = public)
        Path string {mustBeFile(Path)} % Full path to the file
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(comp, event)
            comp.Path = comp.EditField.Value;
            notify(comp,"PathChanged")
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            [fileName,pathName] = uigetfile("","Select a file");
            if ~isequal(fileName,0)
                comp.Path = fullfile(pathName,fileName);
            end

            notify(comp,"PathChanged")
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            comp.EditField.Value = comp.Path;
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 406 49];
            comp.GridLayout = uigridlayout(comp);
            comp.EditField = uieditfield(comp.GridLayout, 'text');
            comp.EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditFieldValueChanged, true);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Icon = fullfile(pathToMLAPP, 'folderIcon.png');
            comp.Button.Text = '';
        end
    end
end