classdef IPAddress < matlab.ui.componentcontainer.ComponentContainer
    %IPADDRESS IP address component
    %   Use the IP address component in an app to provide an input for an
    %   IP address using either the IPv4 or IPv6 protocol. Specify the
    %   protocol using the component Protocol property.

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        IPv6_8      matlab.ui.control.EditField
        Label_7     matlab.ui.control.Label
        IPv6_7      matlab.ui.control.EditField
        Label_6     matlab.ui.control.Label
        IPv6_6      matlab.ui.control.EditField
        Label_5     matlab.ui.control.Label
        IPv6_5      matlab.ui.control.EditField
        Label_4     matlab.ui.control.Label
        IPv6_4      matlab.ui.control.EditField
        Label_3     matlab.ui.control.Label
        IPv6_3      matlab.ui.control.EditField
        Label_2     matlab.ui.control.Label
        IPv6_2      matlab.ui.control.EditField
        Label       matlab.ui.control.Label
        IPv6_1      matlab.ui.control.EditField
        IPv4_4      matlab.ui.control.NumericEditField
        IPv4_3      matlab.ui.control.NumericEditField
        IPv4_2      matlab.ui.control.NumericEditField
        IPv4_1      matlab.ui.control.NumericEditField
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        AddressChanged % Event that triggers when a user changes the IP address
    end

    properties (Access = public)
        Address (1,:) % IP address value
        Protocol (1,1) string {mustBeMember(Protocol, ["IPv4","IPv6"])} = "IPv4"; % IP address protocol
    end
    
    
    methods
        % Custom validation for Address property 
        function set.Address(comp,val)
            % Validate IPv4 address 
            if comp.Protocol == "IPv4"
                if length(val) ~= 4
                    error("IPv4 address must have four fields.")
                end
                mustBeInRange(val,0,255)
        
            % Validate IPv6 address
            else
                if length(val) ~= 8
                    error("IPv6 address must have eight fields.")
                end
        
                if ~isequal(cellfun('length',val),repmat(4,1,8))
                    error("Specify IPv6 field as a four-digit hexadecimal number.")
                end
                
                try 
                    hex2dec(val);
                catch
                    error("Specify IPv6 field as a four-digit hexadecimal number.")
                end
            end
            comp.Address = val;
        end

        function set.Protocol(comp,val)
            comp.Protocol = val;
            if val == "IPv4"
                comp.Address = [comp.IPv4_1.Value comp.IPv4_2.Value ...
                comp.IPv4_3.Value comp.IPv4_4.Value];
            else % IPv6
                comp.Address = {comp.IPv6_1.Value,comp.IPv6_2.Value, ...
                comp.IPv6_3.Value,comp.IPv6_4.Value,comp.IPv6_5.Value ...
                comp.IPv6_6.Value,comp.IPv6_7.Value,comp.IPv6_8.Value};
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % Set default address
            comp.Address = [192 168 0 1];
        end

        % Value changed function: IPv4_1, IPv4_2, IPv4_3, IPv4_4
        function IPv4ValueChanged(comp, event)
            comp.Address = [comp.IPv4_1.Value comp.IPv4_2.Value ...
                comp.IPv4_3.Value comp.IPv4_4.Value];

            notify(comp,"AddressChanged");
        end

        % Value changed function: IPv6_1, IPv6_2, IPv6_3, IPv6_4, IPv6_5, 
        % ...and 3 other components
        function IPv6ValueChanged(comp, event)
            comp.Address = {comp.IPv6_1.Value,comp.IPv6_2.Value, ...
                comp.IPv6_3.Value,comp.IPv6_4.Value,comp.IPv6_5.Value ...
                comp.IPv6_6.Value,comp.IPv6_7.Value,comp.IPv6_8.Value};

            notify(comp,"AddressChanged");
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Update the component based on the protocol
            if comp.Protocol == "IPv4"
                % Hide the second row of edit fields by setting the row
                % height to zero
                comp.GridLayout.RowHeight = {'1x',0};

                % Update edit field display
                comp.IPv4_1.Value = comp.Address(1);
                comp.IPv4_2.Value = comp.Address(2);
                comp.IPv4_3.Value = comp.Address(3);
                comp.IPv4_4.Value = comp.Address(4);
            else %IPv6
                % Hide the first row of edit fields by setting the row
                % height to zero
                comp.GridLayout.RowHeight = {0,'1x'};

                % Update edit field display
                comp.IPv6_1.Value = comp.Address{1};
                comp.IPv6_2.Value = comp.Address{2};
                comp.IPv6_3.Value = comp.Address{3};
                comp.IPv6_4.Value = comp.Address{4};
                comp.IPv6_5.Value = comp.Address{5};
                comp.IPv6_6.Value = comp.Address{6};
                comp.IPv6_7.Value = comp.Address{7};
                comp.IPv6_8.Value = comp.Address{8};
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 420 31];
            comp.GridLayout = uigridlayout(comp);
            comp.IPv4_1 = uieditfield(comp.GridLayout, 'numeric');
            comp.IPv4_1.Limits = [0 255];
            comp.IPv4_1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv4ValueChanged, true);
            comp.IPv4_1.Value = 192;
            comp.IPv4_2 = uieditfield(comp.GridLayout, 'numeric');
            comp.IPv4_2.Limits = [0 255];
            comp.IPv4_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv4ValueChanged, true);
            comp.IPv4_2.Value = 168;
            comp.IPv4_3 = uieditfield(comp.GridLayout, 'numeric');
            comp.IPv4_3.Limits = [0 255];
            comp.IPv4_3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv4ValueChanged, true);
            comp.IPv4_4 = uieditfield(comp.GridLayout, 'numeric');
            comp.IPv4_4.Limits = [0 255];
            comp.IPv4_4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv4ValueChanged, true);
            comp.IPv4_4.Value = 1;
            comp.IPv6_1 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_1.Value = 'FEC0';
            comp.Label = uilabel(comp.GridLayout);
            comp.Label.Text = ':';
            comp.IPv6_2 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_2.Value = '0000';
            comp.Label_2 = uilabel(comp.GridLayout);
            comp.Label_2.Text = ':';
            comp.IPv6_3 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_3.Value = '0000';
            comp.Label_3 = uilabel(comp.GridLayout);
            comp.Label_3.Text = ':';
            comp.IPv6_4 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_4.Value = '0000';
            comp.Label_4 = uilabel(comp.GridLayout);
            comp.Label_4.Text = ':';
            comp.IPv6_5 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_5.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_5.Value = '0000';
            comp.Label_5 = uilabel(comp.GridLayout);
            comp.Label_5.Text = ':';
            comp.IPv6_6 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_6.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_6.Value = '0000';
            comp.Label_6 = uilabel(comp.GridLayout);
            comp.Label_6.Text = ':';
            comp.IPv6_7 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_7.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_7.Value = '0000';
            comp.Label_7 = uilabel(comp.GridLayout);
            comp.Label_7.Text = ':';
            comp.IPv6_8 = uieditfield(comp.GridLayout, 'text');
            comp.IPv6_8.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IPv6ValueChanged, true);
            comp.IPv6_8.Value = '0001';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end