classdef InteractiveBubbleChart < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        BubbleSizeDropDown             matlab.ui.control.DropDown
        BubbleSizeDropDownLabel        matlab.ui.control.Label
        BubbleTransparencySlider       matlab.ui.control.Slider
        BubbleTransparencySliderLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        BubbleSizeChanged % Triggers when a user interacts with the drop-down
        BubbleTransparencyChanged % Triggers when a user interacts with the slider
    end

    properties (Access = private)
        Chart1 % First Chart
        legend
    end
    
    properties (Access = public)
        BubbleSize (1,1) string {mustBeMember(BubbleSize, ["Small", "Medium", "Large"])} = "Medium";
        BubbleTransparency (1,1) double {mustBeInRange(BubbleTransparency, 0,1)} = 0.5;
        ChartTitle char {mustBeText(ChartTitle)} = '';
        LegendTitle char {mustBeText(LegendTitle)} = '';
        MarkerEdgeColor (1,3) double = [0 0 1];
        MarkerFaceColor (1,3) double {mustBeInRange(MarkerFaceColor, 0,1)} = [0 0 1];
        ShowLegend (1,1) logical = false;
        ShowSizeUI (1,1) logical = true;
        ShowTransparencyUI (1,1) logical = true;
        SizeData (1,:) double = NaN;
        XData (1,:) double = NaN;
        XLabel char = 'X';
        YData (1,:) double = NaN;
        YLabel char = 'Y';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.Chart1 = bubblechart(comp.UIAxes,comp.XData,comp.YData,comp.SizeData);
        end

        % Value changed function: BubbleSizeDropDown
        function BubbleSizeDropDownValueChanged(comp, event)
            comp.BubbleSize = comp.BubbleSizeDropDown.Value;

            notify(comp,"BubbleSizeChanged")
        end

        % Value changed function: BubbleTransparencySlider
        function BubbleTransparencySliderValueChanged(comp, event)
            comp.BubbleTransparency = comp.BubbleTransparencySlider.Value;

            notify(comp,"BubbleTransparencyChanged")
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Update chart data 
            comp.Chart1.XData = comp.XData;
            comp.Chart1.YData = comp.YData;
            comp.Chart1.SizeData = comp.SizeData;
           
            % Update chart data appearance
            comp.Chart1.MarkerFaceAlpha = comp.BubbleTransparency;
            comp.Chart1.MarkerFaceColor = comp.MarkerFaceColor;
            comp.Chart1.MarkerEdgeColor = comp.MarkerEdgeColor;

            switch comp.BubbleSize
                case "Small"
                    bubblesize(comp.UIAxes, [5 15]);
                case "Medium"
                    bubblesize(comp.UIAxes, [5 30]);
                case "Large"
                    bubblesize(comp.UIAxes, [3 50]);   
            end

            % Update chart text
            title(comp.UIAxes,comp.ChartTitle);
            xlabel(comp.UIAxes,comp.XLabel);
            ylabel(comp.UIAxes,comp.YLabel);

            % Update visibility of legend and UI components
            if ~comp.ShowLegend
                delete(comp.legend)
            else
                comp.legend = bubblelegend(comp.UIAxes,comp.LegendTitle,Location='eastoutside');
                comp.legend.Visible="on";
            end
            
            if ~comp.ShowSizeUI
                comp.GridLayout.RowHeight{3} = 0;
            else
                comp.GridLayout.RowHeight{3} = 'fit';
            end

            if ~comp.ShowTransparencyUI
                comp.GridLayout.RowHeight{4} = 0;
            else
                comp.GridLayout.RowHeight{4} = 'fit';
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 399 293];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            zlabel(comp.UIAxes, 'Z')
            comp.BubbleTransparencySliderLabel = uilabel(comp.GridLayout);
            comp.BubbleTransparencySliderLabel.Text = 'Bubble Transparency';
            comp.BubbleTransparencySlider = uislider(comp.GridLayout);
            comp.BubbleTransparencySlider.Limits = [0 1];
            comp.BubbleTransparencySlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @BubbleTransparencySliderValueChanged, true);
            comp.BubbleTransparencySlider.Value = 0.5;
            comp.BubbleSizeDropDownLabel = uilabel(comp.GridLayout);
            comp.BubbleSizeDropDownLabel.Text = 'Bubble Size';
            comp.BubbleSizeDropDown = uidropdown(comp.GridLayout);
            comp.BubbleSizeDropDown.Items = {'Small', 'Medium', 'Large'};
            comp.BubbleSizeDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @BubbleSizeDropDownValueChanged, true);
            comp.BubbleSizeDropDown.Value = 'Medium';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end