classdef LabProcedure < matlab.ui.componentcontainer.ComponentContainer
    %LABPROCEDURE A single lab procedure status and data
    %   This component is used in the Lab Procedure app to track and
    %   update data associated with a procedure

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout2         matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        ViewDataButton      matlab.ui.control.Button
        ImportDataButton    matlab.ui.control.Button
        DatePicker          matlab.ui.control.DatePicker
        DateLabel           matlab.ui.control.Label
        StatusDropDown      matlab.ui.control.DropDown
        StatusLabel         matlab.ui.control.Label
        LabProcedure1Label  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        DateChanged
        StatusChanged
    end

    properties (Access = private)
        DataFilename % Path to data file
    end
    
    properties (Access = public)
        Date (1,1) datetime = datetime('today'); % Lab procedure date
        Status (1,1) string {mustBeMember(Status, ["Not started", "Running", "Succeeded", "Failed"])} = "Not started"; % Lab procedure status
        Title (1,1) string {mustBeTextScalar(Title)} = "Lab Procedure 1"; % Lab procedure title
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StatusDropDown
        function StatusDropDownValueChanged(comp, event)
            % Update public property and trigger event 
            comp.Status = comp.StatusDropDown.Value;
            notify(comp,"StatusChanged")
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(comp, event)
            % Update public property and trigger event
            comp.Date = comp.DatePicker.Value;
            notify(comp,"DateChanged");
        end

        % Button pushed function: ViewDataButton
        function ViewDataButtonPushed(comp, event)
            open(comp.DataFilename);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(comp, event)
            [f,p] = uigetfile;

            % Store data
            if f ~= 0
                comp.DataFilename = fullfile(p,f);
                comp.ViewDataButton.Enable = "on";
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Update underlying components
            comp.LabProcedure1Label.Text = comp.Title;
            comp.StatusDropDown.Value = comp.Status;
            comp.DatePicker.Value = comp.Date;
            comp.GridLayout.BackgroundColor = comp.BackgroundColor;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 387 105];
            comp.GridLayout2 = uigridlayout(comp);
            comp.Panel = uipanel(comp.GridLayout2);
            comp.GridLayout = uigridlayout(comp.Panel);
            comp.LabProcedure1Label = uilabel(comp.GridLayout);
            comp.LabProcedure1Label.Text = 'Lab Procedure 1';
            comp.StatusLabel = uilabel(comp.GridLayout);
            comp.StatusLabel.Text = 'Status:';
            comp.StatusDropDown = uidropdown(comp.GridLayout);
            comp.StatusDropDown.Items = {'Not started', 'Running', 'Succeeded', 'Failed'};
            comp.StatusDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @StatusDropDownValueChanged, true);
            comp.StatusDropDown.Value = 'Not started';
            comp.DateLabel = uilabel(comp.GridLayout);
            comp.DateLabel.Text = 'Date:';
            comp.DatePicker = uidatepicker(comp.GridLayout);
            comp.DatePicker.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DatePickerValueChanged, true);
            comp.ImportDataButton = uibutton(comp.GridLayout, 'push');
            comp.ImportDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImportDataButtonPushed, true);
            comp.ImportDataButton.Text = 'Import Data';
            comp.ViewDataButton = uibutton(comp.GridLayout, 'push');
            comp.ViewDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ViewDataButtonPushed, true);
            comp.ViewDataButton.Enable = 'off';
            comp.ViewDataButton.Text = 'View Data';
        end
    end
end