classdef LabProcedureApp_WithComponent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        LabProcedure5         LabProcedure
        LabProcedure4         LabProcedure
        LabProcedure3         LabProcedure
        LabProcedure2         LabProcedure
        LabProcedure1         LabProcedure
        OrderByDropDown       matlab.ui.control.DropDown
        OrderByDropDownLabel  matlab.ui.control.Label
        LabProceduresLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        OrderedProcedures % Lab procedures in the order listed in the app 
    end
    
    methods (Access = private)

        function updateBackgroundColor(app,lp)
            switch lp.Status
                case "Not started"
                    lp.BackgroundColor = [0.94 0.94 0.94];
                case "Running"
                    lp.BackgroundColor = [0.76 0.84 0.87];
                case "Succeeded"
                    lp.BackgroundColor = [0.75 0.87 0.75];
                case "Failed"
                    lp.BackgroundColor = [0.87 0.76 0.75];
            end
        end
        
        function updateProcedureOrder(app)
            switch app.OrderByDropDown.Value
                case "Date"
                    [~, ind] = sort([app.OrderedProcedures.Date]);
                case "Status"
                    [~, ind] = sort([app.OrderedProcedures.Status]);
                case "Title"
                    [~, ind] = sort([app.OrderedProcedures.Title]);
            end
            app.OrderedProcedures = app.OrderedProcedures(ind);
            
            for idx = 1:length(app.OrderedProcedures)
                lp = app.OrderedProcedures(idx);
                lp.Layout.Row = idx;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            lps = findobj(app.UIFigure,Type="LabProcedure");
            for idx = 1:length(lps)
                lp = lps(idx);
                updateBackgroundColor(app,lp)
            end

            app.OrderedProcedures = lps;
            updateProcedureOrder(app);
        end

        % Callback function: LabProcedure1, LabProcedure2, LabProcedure3, 
        % ...and 2 other components
        function LabProcedureStatusChanged(app, event)
            lp = event.Source;
            updateBackgroundColor(app,lp)
            updateProcedureOrder(app)
        end

        % Value changed function: OrderByDropDown
        function OrderByDropDownValueChanged(app, event)
            updateProcedureOrder(app)
        end

        % Callback function: LabProcedure1, LabProcedure2, LabProcedure3, 
        % ...and 2 other components
        function LabProcedureDateChanged(app, event)
            updateProcedureOrder(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LabProceduresLabel = uilabel(app.UIFigure);
            app.LabProceduresLabel.Text = 'Lab Procedures';
            app.OrderByDropDownLabel = uilabel(app.UIFigure);
            app.OrderByDropDownLabel.Text = 'Order By: ';
            app.OrderByDropDown = uidropdown(app.UIFigure);
            app.OrderByDropDown.Items = {'Date', 'Status', 'Title'};
            app.OrderByDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderByDropDownValueChanged, true);
            app.OrderByDropDown.Value = 'Date';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.LabProcedure1 = LabProcedure(app.GridLayout);
            app.LabProcedure1.Date = datetime([2022 2 2]);
            app.LabProcedure1.Status = "Running";
            app.LabProcedure1.Title = "Baseline Condition Test";
            app.LabProcedure1.DateChangedFcn = createCallbackFcn(app, @LabProcedureDateChanged, true);
            app.LabProcedure1.StatusChangedFcn = createCallbackFcn(app, @LabProcedureStatusChanged, true);
            app.LabProcedure2 = LabProcedure(app.GridLayout);
            app.LabProcedure2.Date = datetime([2022 2 2]);
            app.LabProcedure2.Title = "Stability Study";
            app.LabProcedure2.DateChangedFcn = createCallbackFcn(app, @LabProcedureDateChanged, true);
            app.LabProcedure2.StatusChangedFcn = createCallbackFcn(app, @LabProcedureStatusChanged, true);
            app.LabProcedure3 = LabProcedure(app.GridLayout);
            app.LabProcedure3.Date = datetime([2022 2 2]);
            app.LabProcedure3.Title = "Optimization Study";
            app.LabProcedure3.DateChangedFcn = createCallbackFcn(app, @LabProcedureDateChanged, true);
            app.LabProcedure3.StatusChangedFcn = createCallbackFcn(app, @LabProcedureStatusChanged, true);
            app.LabProcedure4 = LabProcedure(app.GridLayout);
            app.LabProcedure4.Date = datetime([2022 2 2]);
            app.LabProcedure4.Status = "Succeeded";
            app.LabProcedure4.Title = "Screening Study";
            app.LabProcedure4.DateChangedFcn = createCallbackFcn(app, @LabProcedureDateChanged, true);
            app.LabProcedure4.StatusChangedFcn = createCallbackFcn(app, @LabProcedureStatusChanged, true);
            app.LabProcedure5 = LabProcedure(app.GridLayout);
            app.LabProcedure5.Date = datetime([2022 2 2]);
            app.LabProcedure5.Status = "Failed";
            app.LabProcedure5.Title = "Deviation Investigation";
            app.LabProcedure5.DateChangedFcn = createCallbackFcn(app, @LabProcedureDateChanged, true);
            app.LabProcedure5.StatusChangedFcn = createCallbackFcn(app, @LabProcedureStatusChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabProcedureApp_WithComponent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end