classdef LabProcedureApp_WithoutComponent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        Panel_6                      matlab.ui.container.Panel
        GridLayout2_5                matlab.ui.container.GridLayout
        ViewDataButton_5             matlab.ui.control.Button
        ImportDataButton_5           matlab.ui.control.Button
        DatePicker_5                 matlab.ui.control.DatePicker
        DateLabel_5                  matlab.ui.control.Label
        StatusDropDown_5             matlab.ui.control.DropDown
        StatusLabel_5                matlab.ui.control.Label
        DeviationInvestigationLabel  matlab.ui.control.Label
        Panel_5                      matlab.ui.container.Panel
        GridLayout2_4                matlab.ui.container.GridLayout
        ViewDataButton_4             matlab.ui.control.Button
        ImportDataButton_4           matlab.ui.control.Button
        DatePicker_4                 matlab.ui.control.DatePicker
        DateLabel_4                  matlab.ui.control.Label
        StatusDropDown_4             matlab.ui.control.DropDown
        StatusLabel_4                matlab.ui.control.Label
        ScreeningStudyLabel          matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        GridLayout2_3                matlab.ui.container.GridLayout
        ViewDataButton_3             matlab.ui.control.Button
        ImportDataButton_3           matlab.ui.control.Button
        DatePicker_3                 matlab.ui.control.DatePicker
        DateLabel_3                  matlab.ui.control.Label
        StatusDropDown_3             matlab.ui.control.DropDown
        StatusLabel_3                matlab.ui.control.Label
        OptimizationStudyLabel       matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        GridLayout2_2                matlab.ui.container.GridLayout
        ViewDataButton_2             matlab.ui.control.Button
        ImportDataButton_2           matlab.ui.control.Button
        DatePicker_2                 matlab.ui.control.DatePicker
        DateLabel_2                  matlab.ui.control.Label
        StatusDropDown_2             matlab.ui.control.DropDown
        StatusLabel_2                matlab.ui.control.Label
        StabilityStudyLabel          matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        ViewDataButton               matlab.ui.control.Button
        ImportDataButton             matlab.ui.control.Button
        DatePicker                   matlab.ui.control.DatePicker
        DateLabel                    matlab.ui.control.Label
        StatusDropDown               matlab.ui.control.DropDown
        StatusLabel                  matlab.ui.control.Label
        BaselineConditionTestLabel   matlab.ui.control.Label
        OrderByDropDown              matlab.ui.control.DropDown
        OrderByDropDownLabel         matlab.ui.control.Label
        LabProceduresLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        OrderedProcedures % Procedure panels in the order listed in the app
    end
    
    methods (Access = private)
        
        function updateBackgroundColor(app,dd)
            % Find the grid layout the contains the drop-down component
            % and update its background color
            gl = ancestor(dd,"uigridlayout");
            switch dd.Value
                case "Not started"
                    gl.BackgroundColor = [0.94 0.94 0.94];
                case "Running"
                    gl.BackgroundColor = [0.76 0.84 0.87];
                case "Succeeded"
                    gl.BackgroundColor = [0.75 0.87 0.75];
                case "Failed"
                    gl.BackgroundColor = [0.87 0.76 0.75];
            end
        end
        
        function updateProcedureOrder(app)
            numProcedures = length(app.OrderedProcedures);
            switch app.OrderByDropDown.Value
                case "Date"
                    % Find the date associated with each panel
                    dateList = NaT(1,numProcedures);
                    for k = 1:numProcedures
                        pnl = app.OrderedProcedures(k);
                        d = findobj(pnl,Type="uidatepicker");
                        dateList(k) = d.Value;
                    end
                    % Sort dates
                    [~, ind] = sort(dateList);
                case "Status"
                    % Find the status associated with each panel
                    statusList = strings(1,numProcedures);
                    for k = 1:numProcedures
                        pnl = app.OrderedProcedures(k);
                        dd = findobj(pnl,"Type","uidropdown");
                        statusList(k) = dd.Value;
                    end
                    % Sort statuses
                    [~, ind] = sort(statusList);
                case "Title"
                    % Find the title associated with each panel
                    titleList = strings(1,numProcedures);
                    for k = 1:numProcedures
                        pnl = app.OrderedProcedures(k);
                        t = findobj(pnl,"Type","uilabel","FontWeight","bold");
                        titleList(k) = t.Value;
                    end
                    % Sort titles
                    [~, ind] = sort([app.OrderedProcedures.Title]);
            end
            app.OrderedProcedures = app.OrderedProcedures(ind);

            for idx = 1:length(app.OrderedProcedures)
                lp = app.OrderedProcedures(idx);
                lp.Layout.Row = idx;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            procedurePanels = [app.Panel_2 app.Panel_3 app.Panel_4 ...
                app.Panel_5 app.Panel_6];
            
            % Update the color of each panel based on the status
            for idx = 1:length(procedurePanels)
                lp = procedurePanels(idx);
                dd = findobj(lp,"Type","uidropdown");
                updateBackgroundColor(app,dd);
            end
            
            % Sort the panels based on the order by drop-down component
            % value
            app.OrderedProcedures = procedurePanels;
            updateProcedureOrder(app);
        end

        % Value changed function: StatusDropDown, StatusDropDown_2, 
        % ...and 3 other components
        function StatusDropDownValueChanged(app, event)
            dd = event.Source;
            updateBackgroundColor(app,dd);
            updateProcedureOrder(app);
        end

        % Value changed function: OrderByDropDown
        function OrderByDropDownValueChanged(app, event)
            updateProcedureOrder(app);
        end

        % Button pushed function: ImportDataButton, ImportDataButton_2, 
        % ...and 3 other components
        function ImportDataButtonPushed(app, event)
            [f,p] = uigetfile;

            % Store data in the View Data button's UserData and enable the
            % button
            if f ~= 0
                dataFilename = fullfile(p,f);
                importButton = event.Source;
                viewButton = findobj(importButton.Parent,"Text","View Data");
                viewButton.UserData = dataFilename;
                viewButton.Enable = "on";
            end
        end

        % Button pushed function: ViewDataButton, ViewDataButton_2, 
        % ...and 3 other components
        function ViewDataButtonPushed(app, event)
            dataFileName = event.Source.UserData;
            open(dataFileName);
        end

        % Value changed function: DatePicker, DatePicker_2, DatePicker_3, 
        % ...and 2 other components
        function DatePickerValueChanged(app, event)
            updateProcedureOrder(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LabProceduresLabel = uilabel(app.UIFigure);
            app.LabProceduresLabel.Text = 'Lab Procedures';
            app.OrderByDropDownLabel = uilabel(app.UIFigure);
            app.OrderByDropDownLabel.Text = 'Order By: ';
            app.OrderByDropDown = uidropdown(app.UIFigure);
            app.OrderByDropDown.Items = {'Date', 'Status', 'Title'};
            app.OrderByDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderByDropDownValueChanged, true);
            app.OrderByDropDown.Value = 'Date';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.BaselineConditionTestLabel = uilabel(app.GridLayout2);
            app.BaselineConditionTestLabel.Text = 'Baseline Condition Test';
            app.StatusLabel = uilabel(app.GridLayout2);
            app.StatusLabel.Text = 'Status:';
            app.StatusDropDown = uidropdown(app.GridLayout2);
            app.StatusDropDown.Items = {'Not started', 'Running', 'Succeeded', 'Failed'};
            app.StatusDropDown.ValueChangedFcn = createCallbackFcn(app, @StatusDropDownValueChanged, true);
            app.StatusDropDown.Value = 'Running';
            app.DateLabel = uilabel(app.GridLayout2);
            app.DateLabel.Text = 'Date:';
            app.DatePicker = uidatepicker(app.GridLayout2);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2022 2 2]);
            app.ImportDataButton = uibutton(app.GridLayout2, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ViewDataButton = uibutton(app.GridLayout2, 'push');
            app.ViewDataButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton.Enable = 'off';
            app.ViewDataButton.Text = 'View Data';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2_2 = uigridlayout(app.Panel_3);
            app.StabilityStudyLabel = uilabel(app.GridLayout2_2);
            app.StabilityStudyLabel.Text = 'Stability Study';
            app.StatusLabel_2 = uilabel(app.GridLayout2_2);
            app.StatusLabel_2.Text = 'Status:';
            app.StatusDropDown_2 = uidropdown(app.GridLayout2_2);
            app.StatusDropDown_2.Items = {'Not started', 'Running', 'Succeeded', 'Failed'};
            app.StatusDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StatusDropDownValueChanged, true);
            app.StatusDropDown_2.Value = 'Not started';
            app.DateLabel_2 = uilabel(app.GridLayout2_2);
            app.DateLabel_2.Text = 'Date:';
            app.DatePicker_2 = uidatepicker(app.GridLayout2_2);
            app.DatePicker_2.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker_2.Value = datetime([2022 2 2]);
            app.ImportDataButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.ImportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton_2.Text = 'Import Data';
            app.ViewDataButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.ViewDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton_2.Enable = 'off';
            app.ViewDataButton_2.Text = 'View Data';
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout2_3 = uigridlayout(app.Panel_4);
            app.OptimizationStudyLabel = uilabel(app.GridLayout2_3);
            app.OptimizationStudyLabel.Text = 'Optimization Study';
            app.StatusLabel_3 = uilabel(app.GridLayout2_3);
            app.StatusLabel_3.Text = 'Status:';
            app.StatusDropDown_3 = uidropdown(app.GridLayout2_3);
            app.StatusDropDown_3.Items = {'Not started', 'Running', 'Succeeded', 'Failed'};
            app.StatusDropDown_3.ValueChangedFcn = createCallbackFcn(app, @StatusDropDownValueChanged, true);
            app.StatusDropDown_3.Value = 'Not started';
            app.DateLabel_3 = uilabel(app.GridLayout2_3);
            app.DateLabel_3.Text = 'Date:';
            app.DatePicker_3 = uidatepicker(app.GridLayout2_3);
            app.DatePicker_3.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker_3.Value = datetime([2022 2 2]);
            app.ImportDataButton_3 = uibutton(app.GridLayout2_3, 'push');
            app.ImportDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton_3.Text = 'Import Data';
            app.ViewDataButton_3 = uibutton(app.GridLayout2_3, 'push');
            app.ViewDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton_3.Enable = 'off';
            app.ViewDataButton_3.Text = 'View Data';
            app.Panel_5 = uipanel(app.GridLayout);
            app.GridLayout2_4 = uigridlayout(app.Panel_5);
            app.ScreeningStudyLabel = uilabel(app.GridLayout2_4);
            app.ScreeningStudyLabel.Text = 'Screening Study';
            app.StatusLabel_4 = uilabel(app.GridLayout2_4);
            app.StatusLabel_4.Text = 'Status:';
            app.StatusDropDown_4 = uidropdown(app.GridLayout2_4);
            app.StatusDropDown_4.Items = {'Not started', 'Running', 'Succeeded', 'Failed'};
            app.StatusDropDown_4.ValueChangedFcn = createCallbackFcn(app, @StatusDropDownValueChanged, true);
            app.StatusDropDown_4.Value = 'Succeeded';
            app.DateLabel_4 = uilabel(app.GridLayout2_4);
            app.DateLabel_4.Text = 'Date:';
            app.DatePicker_4 = uidatepicker(app.GridLayout2_4);
            app.DatePicker_4.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker_4.Value = datetime([2022 2 2]);
            app.ImportDataButton_4 = uibutton(app.GridLayout2_4, 'push');
            app.ImportDataButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton_4.Text = 'Import Data';
            app.ViewDataButton_4 = uibutton(app.GridLayout2_4, 'push');
            app.ViewDataButton_4.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton_4.Enable = 'off';
            app.ViewDataButton_4.Text = 'View Data';
            app.Panel_6 = uipanel(app.GridLayout);
            app.GridLayout2_5 = uigridlayout(app.Panel_6);
            app.DeviationInvestigationLabel = uilabel(app.GridLayout2_5);
            app.DeviationInvestigationLabel.Text = 'Deviation Investigation';
            app.StatusLabel_5 = uilabel(app.GridLayout2_5);
            app.StatusLabel_5.Text = 'Status:';
            app.StatusDropDown_5 = uidropdown(app.GridLayout2_5);
            app.StatusDropDown_5.Items = {'Not started', 'Running', 'Succeeded', 'Failed'};
            app.StatusDropDown_5.ValueChangedFcn = createCallbackFcn(app, @StatusDropDownValueChanged, true);
            app.StatusDropDown_5.Value = 'Failed';
            app.DateLabel_5 = uilabel(app.GridLayout2_5);
            app.DateLabel_5.Text = 'Date:';
            app.DatePicker_5 = uidatepicker(app.GridLayout2_5);
            app.DatePicker_5.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker_5.Value = datetime([2022 2 2]);
            app.ImportDataButton_5 = uibutton(app.GridLayout2_5, 'push');
            app.ImportDataButton_5.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton_5.Text = 'Import Data';
            app.ViewDataButton_5 = uibutton(app.GridLayout2_5, 'push');
            app.ViewDataButton_5.ButtonPushedFcn = createCallbackFcn(app, @ViewDataButtonPushed, true);
            app.ViewDataButton_5.Enable = 'off';
            app.ViewDataButton_5.Text = 'View Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabProcedureApp_WithoutComponent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end