classdef PlotCurve < matlab.apps.AppBase
    %PLOTCURVE Interactively plot the curve y = fcn(x)
    %   This app plots the curve y = fcn(x) for various functions, fcn.
    %   
    %   Use the dropdown (fcnDropDown) to select a function to plot.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        fcnDropDown       matlab.ui.control.DropDown
        fcnDropDownLabel  matlab.ui.control.Label
        StopButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        h % Line object
        X % x-axis data
    end
    
    methods (Access = private)
        function fcn = getFunction(app)
            switch app.fcnDropDown.Value
                case "Straight Line (y = x)"
                    fcn = @straightLine;
                case "Parabola (y = x^2)"
                    fcn = @parabola;
            end
        end
        
        function createData(app)
            % Create data for the x-axis and y-axis.
            % Update a plot while the data is being created.

            % Get function
            fcn = app.getFunction;

            % x-axis data
            app.X = 5 * 1:100;

            % y-axis data
            y = zeros(1,numel(app.X));
            for i = 1:numel(app.X)
                % Create y-axis data using y = fcn(x)
                y(i) = fcn(app.X(i));

                % Update the plot
                app.updatePlot(y(i),i)
            end
    
            % Toggle buttons
            app.toggleButtons
        end

        function updatePlot(app,y,idx)
            % Update the plot using results.
            
            if isempty(app.h)
                % Create a new plot
                app.h = plot(app.UIAxes,app.X(idx),y,'o');
                xlim(app.UIAxes,[app.X(1) app.X(end)])
            else
                % Update the plot
                app.h.XData = [app.h.XData app.X(idx)];
                app.h.YData = [app.h.YData y];
                drawnow
            end
        end
        
        function clearPlot(app)
            % Clear the plot
            if ~isempty(app.h)
                app.h = [];
            end
        end

        function toggleButtons(app)
            app.StartButton.Enable = ~app.StartButton.Enable;
            app.StopButton.Enable = ~app.StopButton.Enable;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: fcnDropDown
        function fcnDropDownValueChanged(app, event)
            app.clearPlot
            
            if app.StartButton.Enable == false
                app.createData
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.toggleButtons
            app.clearPlot
            app.createData
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.toggleButtons
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot of y = fcn(x)')
            xlabel(app.UIAxes, {''; 'x'})
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.fcnDropDownLabel = uilabel(app.UIFigure);
            app.fcnDropDownLabel.Text = 'fcn';
            app.fcnDropDown = uidropdown(app.UIFigure);
            app.fcnDropDown.Items = {'Straight Line (y = x)', 'Parabola (y = x^2)'};
            app.fcnDropDown.ValueChangedFcn = createCallbackFcn(app, @fcnDropDownValueChanged, true);
            app.fcnDropDown.Value = 'Straight Line (y = x)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotCurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end