classdef PolarPlotAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Panel            matlab.ui.container.Panel
        DropDown         matlab.ui.control.DropDown
        bEditField       matlab.ui.control.NumericEditField
        bEditFieldLabel  matlab.ui.control.Label
        aEditField       matlab.ui.control.NumericEditField
        aEditFieldLabel  matlab.ui.control.Label
        EquationLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            % Define theta. Get a and b
            theta = 0:pi/1800:2*pi;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            
            % Calculate r and plot it
            r = (1 + sin(a.*theta)).^(1/b);
            plotline = polarplot(app.Pax,theta,r);
            
            % Set line color
            plotline.Color = app.DropDown.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create polar axes in the panel
            app.Pax = polaraxes(app.Panel);
            
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: DropDown, aEditField, bEditField
        function ComponentValueChanged(app, event)
            % Plot the polar function
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EquationLabel = uilabel(app.GridLayout);
            app.EquationLabel.Interpreter = 'latex';
            app.EquationLabel.Text = '$$r = (1+\sin(a\theta))^{1/b}}$$';
            app.aEditFieldLabel = uilabel(app.GridLayout);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.GridLayout, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.aEditField.Value = 5;
            app.bEditFieldLabel = uilabel(app.GridLayout);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout, 'numeric');
            app.bEditField.Limits = [2.22044604925031e-16 Inf];
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.bEditField.Value = 2;
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Blue Line', 'Red Line', 'Green Line', 'Black Line'};
            app.DropDown.ItemsData = {'b', 'r', 'g', 'k'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.DropDown.Value = 'b';
            app.Panel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarPlotAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end