classdef PulseGeneratorWithClass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PulseGeneratorUIFigure       matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        DispersionKnobLabel          matlab.ui.control.Label
        HighPassKnobLabel            matlab.ui.control.Label
        LowPassKnobLabel             matlab.ui.control.Label
        DispersionKnob               matlab.ui.control.DiscreteKnob
        HighPassKnob                 matlab.ui.control.Knob
        LowPassKnob                  matlab.ui.control.Knob
        ModulationKnobLabel          matlab.ui.control.Label
        WindowKnobLabel              matlab.ui.control.Label
        EdgeKnobLabel                matlab.ui.control.Label
        ModulationKnob               matlab.ui.control.DiscreteKnob
        WindowKnob                   matlab.ui.control.Knob
        EdgeKnob                     matlab.ui.control.Knob
        TypeDropDown                 matlab.ui.control.DropDown
        TypeDropDownLabel            matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        SignalLengthsEditField       matlab.ui.control.NumericEditField
        SignalLengthsEditFieldLabel  matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        AutoUpdateLamp               matlab.ui.control.Lamp
        AutoUpdateSwitch             matlab.ui.control.Switch
        AutoUpdateSwitchLabel        matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        PlotTypeSwitch               matlab.ui.control.Switch
        PulsePlotUIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        autoUpdate
        PulseObject
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            app.PulseObject.Type = app.TypeDropDown.Value;
            app.PulseObject.Frequency = app.FrequencyEditField.Value;
            app.PulseObject.Length = app.SignalLengthsEditField.Value;
            app.PulseObject.Edge = app.EdgeKnob.Value;
            app.PulseObject.Window = app.WindowKnob.Value;  
            app.PulseObject.Modulation = str2double(app.ModulationKnob.Value);
            app.PulseObject.LowPass = app.LowPassKnob.Value;
            app.PulseObject.HighPass = app.HighPassKnob.Value;
            app.PulseObject.Dispersion = str2double(app.DispersionKnob.Value);
            
            signalLength = app.SignalLengthsEditField.Value ;
            frequency = app.FrequencyEditField.Value ;
            plotType = app.PlotTypeSwitch.Value ;
            
            p = generatePulse(app.PulseObject);
            t = -signalLength/2:1/frequency:signalLength/2;
            
            if strcmp(plotType,'Pulse')
                plot(app.PulsePlotUIAxes, t, p);
                xlabel(app.PulsePlotUIAxes,'Time(s)');
            else
                lp=length(p);
                Y=fft(p);
                sig=abs(Y(1:ceil(lp/2)));
                f=linspace(0, frequency/2, ceil(lp/2));
                plot(app.PulsePlotUIAxes, f(sig>1e-4), sig(sig>1e-4));
                % semilogy(app.PulsePlotUIAxes, f(sig>1e-4), sig(sig>1e-4));
                xlabel(app.PulsePlotUIAxes,'Frequency (Hz)');
            end            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PulseObject = Pulse;
            app.AutoUpdateSwitch.Value = 'on';
            app.PlotTypeSwitch.Value = 'Pulse' ;
            app.PlotButton.Enable = 'off' ;
            app.autoUpdate = 1 ;
            updatePlot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PulseGeneratorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {493, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updatePlot(app)
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            if strcmp(app.AutoUpdateSwitch.Value, 'on')
                app.autoUpdate = 1;
                app.PlotButton.Enable = 'off';
                app.AutoUpdateLamp.Color = [0 1 0];
            else
                app.autoUpdate = 0;
                app.PlotButton.Enable = 'on';
                app.AutoUpdateLamp.Color = [0.5 0.5 0.5];
            end        
        end

        % Value changed function: DispersionKnob, EdgeKnob, 
        % ...and 8 other components
        function KnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PulseGeneratorUIFigure = uifigure('Visible', 'off');
            app.PulseGeneratorUIFigure.Name = 'Pulse Generator';
            app.PulseGeneratorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PulseGeneratorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyEditField.Limits = [100 1000];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.FrequencyEditField.Value = 500;
            app.AutoUpdateSwitchLabel = uilabel(app.LeftPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto Update';
            app.AutoUpdateSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AutoUpdateSwitch.Items = {'off', 'on'};
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.AutoUpdateSwitch.Value = 'on';
            app.AutoUpdateLamp = uilamp(app.LeftPanel);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.SignalLengthsEditFieldLabel = uilabel(app.LeftPanel);
            app.SignalLengthsEditFieldLabel.Text = 'Signal Length (s)';
            app.SignalLengthsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SignalLengthsEditField.Limits = [1 600];
            app.SignalLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.SignalLengthsEditField.Value = 2;
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.TypeDropDownLabel = uilabel(app.LeftPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.LeftPanel);
            app.TypeDropDown.Items = {'gaussian', 'sinc', 'square', 'triangle', 'monocycle', 'exponential', 'biexponential', 'mexican hat', 'raised cosine', 'double sinc', 'sinc squared', 'sweep'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.TypeDropDown.Value = 'gaussian';
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.EdgeKnob = uiknob(app.GridLayout2, 'continuous');
            app.EdgeKnob.Limits = [0 4];
            app.EdgeKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.EdgeKnob.Value = 1;
            app.WindowKnob = uiknob(app.GridLayout2, 'continuous');
            app.WindowKnob.Limits = [0 1];
            app.WindowKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.WindowKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ModulationKnob = uiknob(app.GridLayout2, 'discrete');
            app.ModulationKnob.Items = {'0', '50', '100', '150', '200', '250'};
            app.ModulationKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ModulationKnob.Value = '0';
            app.EdgeKnobLabel = uilabel(app.GridLayout2);
            app.EdgeKnobLabel.Text = 'Edge';
            app.WindowKnobLabel = uilabel(app.GridLayout2);
            app.WindowKnobLabel.Text = 'Window';
            app.ModulationKnobLabel = uilabel(app.GridLayout2);
            app.ModulationKnobLabel.Text = 'Modulation';
            app.LowPassKnob = uiknob(app.GridLayout2, 'continuous');
            app.LowPassKnob.Limits = [0 1];
            app.LowPassKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.LowPassKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.LowPassKnob.Value = 0.4;
            app.HighPassKnob = uiknob(app.GridLayout2, 'continuous');
            app.HighPassKnob.Limits = [0 1];
            app.HighPassKnob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.HighPassKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.DispersionKnob = uiknob(app.GridLayout2, 'discrete');
            app.DispersionKnob.Items = {'-1.0', '-0.5', '0', '0.5', '1.0'};
            app.DispersionKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.DispersionKnob.Value = '0';
            app.LowPassKnobLabel = uilabel(app.GridLayout2);
            app.LowPassKnobLabel.Text = 'Low Pass';
            app.HighPassKnobLabel = uilabel(app.GridLayout2);
            app.HighPassKnobLabel.Text = 'High Pass';
            app.DispersionKnobLabel = uilabel(app.GridLayout2);
            app.DispersionKnobLabel.Text = 'Dispersion';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.PulsePlotUIAxes = uiaxes(app.GridLayout3);
            xlabel(app.PulsePlotUIAxes, 'X')
            ylabel(app.PulsePlotUIAxes, 'Y')
            app.PlotTypeSwitch = uiswitch(app.GridLayout3, 'slider');
            app.PlotTypeSwitch.Items = {'Pulse', 'Pulse Spectrum'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.PlotTypeSwitch.Value = 'Pulse Spectrum';
            % Show the figure after all components are created
            app.PulseGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PulseGeneratorWithClass

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PulseGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PulseGeneratorUIFigure)
        end
    end
end