classdef RGBPicker < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout    matlab.ui.container.GridLayout
        BSlider       matlab.ui.control.Slider
        BSliderLabel  matlab.ui.control.Label
        GSlider       matlab.ui.control.Slider
        GSliderLabel  matlab.ui.control.Label
        RSlider       matlab.ui.control.Slider
        RSliderLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ColorChanged % Event that fires when a user changes the RGB value
    end

    properties (Access = public)
        RGB (1,3) double {mustBeInRange(RGB, 0,1)} = [0 0 0]; % RGB color value
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BSlider, GSlider, RSlider
        function SliderValueChanged(comp, event)
            prevRGB = comp.RGB;
            newRGB = [comp.RSlider.Value comp.GSlider.Value comp.BSlider.Value];
            comp.RGB = newRGB;
            eventData = RGBEventData(prevRGB,newRGB);
            notify(comp,"ColorChanged",eventData)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.RSlider.Value = comp.RGB(1);
            comp.GSlider.Value = comp.RGB(2);
            comp.BSlider.Value = comp.RGB(3);
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 246 144];
            comp.GridLayout = uigridlayout(comp);
            comp.RSliderLabel = uilabel(comp.GridLayout);
            comp.RSliderLabel.Text = 'R';
            comp.RSlider = uislider(comp.GridLayout);
            comp.RSlider.Limits = [0 1];
            comp.RSlider.MajorTicks = [0 1];
            comp.RSlider.MajorTickLabels = {'0', '1'};
            comp.RSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderValueChanged, true);
            comp.GSliderLabel = uilabel(comp.GridLayout);
            comp.GSliderLabel.Text = 'G';
            comp.GSlider = uislider(comp.GridLayout);
            comp.GSlider.Limits = [0 1];
            comp.GSlider.MajorTicks = [0 1];
            comp.GSlider.MajorTickLabels = {'0', '1'};
            comp.GSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderValueChanged, true);
            comp.BSliderLabel = uilabel(comp.GridLayout);
            comp.BSliderLabel.Text = 'B';
            comp.BSlider = uislider(comp.GridLayout);
            comp.BSlider.Limits = [0 1];
            comp.BSlider.MajorTicks = [0 1];
            comp.BSlider.MajorTickLabels = {'0', '1'};
            comp.BSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SliderValueChanged, true);
        end
    end
end