classdef StylableText < matlab.ui.componentcontainer.ComponentContainer
    %STYLABLETEXT Custom component with enumeration example

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        Label       matlab.ui.control.Label
    end

    properties (Access = public)
        Scale double {mustBeGreaterThan(Scale, 0)} = 1;
        Style TextStyle = TextStyle.Title;
        Text string = "Example";
        WordWrap matlab.lang.OnOffSwitchState = matlab.lang.OnOffSwitchState.on;
    end
    
    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Update Style 
            comp.Label.FontSize = comp.Style.FontSize*comp.Scale;
            comp.Label.FontColor = comp.Style.FontColor;
            % Update Text
            comp.Label.Text = comp.Text;
            % Update WordWrap
            comp.Label.WordWrap = comp.WordWrap;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 282 127];
            comp.GridLayout = uigridlayout(comp);
            comp.Label = uilabel(comp.GridLayout);
            comp.Label.Text = 'Example';
        end
    end
end