classdef ThemedTsunamiApp < matlab.apps.AppBase
    %THEMEDTSUNAMIAPP App that works well in both light and dark theme
    %   This example app demonstrates how to specify colors, icons, and
    %   images in an app so that the app appears readable in both light and
    %   dark theme.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        PlotValidDataButton           matlab.ui.control.Button
        MinimalValidityDropDown       matlab.ui.control.DropDown
        MinimalValidityDropDownLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        TsunamiAppLabel               matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
   properties (Access = private)
        Data % Tsunami table data
        ValidStyle % Style for table rows with valid tsunami data
        InvalidStyle % Style for table rows with invalid tsunami data
        ValidInds % Indices of valid table rows
        InvalidInds % Indices of invalid table rows
        GeoBubble % Object for plotting tsunami data
        RGBImage % Truecolor data for tsunami image
    end
    
    methods (Access = private)
        
        function calculateValidIndices(app)
            % Find table rows corresponding to valid tsunamis
            minvalidity = lower(app.MinimalValidityDropDown.Value);
            isvalid = minvalidity <= app.Data.Validity;
            app.ValidInds = find(isvalid);
            app.InvalidInds = find(~isvalid);
        end

        function updateTableColors(app)
            % Apply styles to table rows
            removeStyle(app.UITable)
            addStyle(app.UITable,app.ValidStyle,"row",app.ValidInds)
            addStyle(app.UITable,app.InvalidStyle,"row",app.InvalidInds)
        end
        
        function plotValidTsunamis(app)
            % Update geographic bubble chart with valid tsunami data
            app.GeoBubble.LatitudeData = app.Data.Latitude(app.ValidInds);
            app.GeoBubble.LongitudeData = app.Data.Longitude(app.ValidInds);
            app.GeoBubble.SizeData = app.Data.MaxHeight(app.ValidInds);
        end
        
        function updateCustomColors(app)
            % Update table and image colors based on the figure theme
            validcolor = "#90EE90"; % light green
            invalidcolor = "#FFCCCB"; % light red
            im = app.RGBImage;
            if app.UIFigure.Theme.BaseColorStyle == "light"
                app.ValidStyle.BackgroundColor = validcolor;
                app.InvalidStyle.BackgroundColor = invalidcolor;
                app.Image.ImageSource = im;
            elseif app.UIFigure.Theme.BaseColorStyle == "dark"
                app.ValidStyle.BackgroundColor = fliplightness(validcolor);
                app.InvalidStyle.BackgroundColor = fliplightness(invalidcolor);
                app.Image.ImageSource = fliplightness(im);
            end
            updateTableColors(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load in tsunami data and populate the table
            t = readtable("tsunamis.xlsx");
            vars = ["Latitude","Longitude","MaxHeight","Validity"];
            app.Data = t(:,vars);
            catvalues = ["very doubtful tsunami","questionable tsunami", ...
                "probable tsunami","definite tsunami"];
            app.Data.Validity = categorical(app.Data.Validity,catvalues,Ordinal=true);
            app.UITable.Data = app.Data;

            % Read in PNG image as truecolor array
            [A,map] = imread("tsunamiImage.png");
            app.RGBImage = ind2rgb(A,map);

            % Create placeholder objects
            app.GeoBubble = geobubble(app.Panel,[],[],LegendVisible="off");
            app.ValidStyle = uistyle;
            app.InvalidStyle = uistyle;
    
            % Update colors and plot
            calculateValidIndices(app);
            updateCustomColors(app);
            plotValidTsunamis(app);
        end

        % Value changed function: MinimalValidityDropDown
        function MinimalValidityDropDownValueChanged(app, event)
            calculateValidIndices(app);
            updateTableColors(app);
        end

        % Button pushed function: PlotValidDataButton
        function PlotValidDataButtonPushed(app, event)
            plotValidTsunamis(app);
        end

        % Theme changed function: UIFigure
        function UIFigureThemeChanged(app, event)
            updateCustomColors(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'tsunamiImage.png';
            app.TsunamiAppLabel = uilabel(app.GridLayout);
            app.TsunamiAppLabel.Text = 'Tsunami App';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Latitude'; 'Longitude'; 'MaxHeight'; 'Validity'};
            app.UITable.RowName = {};
            app.MinimalValidityDropDownLabel = uilabel(app.GridLayout);
            app.MinimalValidityDropDownLabel.Text = 'Minimal Validity:';
            app.MinimalValidityDropDown = uidropdown(app.GridLayout);
            app.MinimalValidityDropDown.Items = {'Very doubtful tsunami', 'Questionable tsunami', 'Probable tsunami', 'Definite tsunami'};
            app.MinimalValidityDropDown.ValueChangedFcn = createCallbackFcn(app, @MinimalValidityDropDownValueChanged, true);
            app.MinimalValidityDropDown.Value = 'Very doubtful tsunami';
            app.PlotValidDataButton = uibutton(app.GridLayout, 'push');
            app.PlotValidDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotValidDataButtonPushed, true);
            app.PlotValidDataButton.Icon = 'success';
            app.PlotValidDataButton.Text = 'Plot Valid Data';
            app.Panel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThemedTsunamiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end