classdef WindSpeedAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WindSpeedUIFigure           matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        MeasuredEvery5SecondsLabel  matlab.ui.control.Label
        WindSpeedinNatickMALabel    matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        WindSpeedTimer % Timer object
    end

    methods (Access = private)
    
       function WindSpeedTimerFcn(app,src,event)
            % Get current time
            tnow = datetime("now","TimeZone","America/New_York");
            
            % Get wind speed data from MathWorks weather station 
            weatherdata = webread("https://api.thingspeak.com/channels/12397/feeds/last.json");
            y = str2double(weatherdata.field2);
            
            % Plot data and adjust XLim if current time is out of bounds
            xLL = app.UIAxes.XLim(1);
            xUL = app.UIAxes.XLim(2);
            if(tnow > xUL)
               xtra = seconds(15);
               app.UIAxes.XLim = [xLL+xtra tnow+xtra];
               app.UIAxes.XTick = linspace(xLL+xtra,tnow+xtra,5);
            end
            plot(app.UIAxes,tnow,y,"ob");
            
            % Adjust YLim to show variations better
            yLL = max(y-10, 0); 
            yUL = y + 10;
            app.UIAxes.YLim = [yLL yUL];
            app.UIAxes.YTick = round(linspace(yLL,yUL,5),2,"significant");
       end        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Plot invisible data point to initialize datetime ruler
            plot(app.UIAxes, datetime("now","TimeZone","America/New_York","Format","hh:mm:ss"),50,"MarkerEdgeColor","none");
           
            % Set x limits and ticks
            t = datetime("now","TimeZone","America/New_York","Format","hh:mm:ss") + seconds(1:60);
            xLL = t(1) - seconds(5);
            xUL = t(length(t));
            app.UIAxes.XLim = [xLL xUL];
            app.UIAxes.XAxis.TickLabelFormat = 'hh:mm:ss';
            app.UIAxes.XTick = linspace(xLL,xUL,5);
            
            % Set y limits and ticks
            app.UIAxes.YLim = [0 50];
            app.UIAxes.YTick = round(linspace(0,50,5),2,"significant");
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
            
            % Create timer object
            app.WindSpeedTimer = timer(...
            "ExecutionMode","fixedRate", ...      % Run timer repeatedly
            "Period",5, ...                       % Period is 5 seconds
            "BusyMode","queue",...                % Queue timer callbacks when busy
            "TimerFcn",@app.WindSpeedTimerFcn);   % Callback that runs every period
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.WindSpeedTimer.Running,"off")
               % Start timer
               start(app.WindSpeedTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop timer
            stop(app.WindSpeedTimer);
        end

        % Close request function: WindSpeedUIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and figure
            stop(app.WindSpeedTimer);
            delete(app.WindSpeedTimer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WindSpeedUIFigure = uifigure('Visible', 'off');
            app.WindSpeedUIFigure.Name = 'Wind Speed';
            app.WindSpeedUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.WindSpeedUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'Time in Natick, MA')
            ylabel(app.UIAxes, 'Speed (mph)')
            app.WindSpeedinNatickMALabel = uilabel(app.GridLayout);
            app.WindSpeedinNatickMALabel.Text = 'Wind Speed in Natick, MA';
            app.MeasuredEvery5SecondsLabel = uilabel(app.GridLayout);
            app.MeasuredEvery5SecondsLabel.Text = 'Measured Every 5 Seconds';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.WindSpeedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WindSpeedAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WindSpeedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WindSpeedUIFigure)
        end
    end
end